classdef Imagenes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FactorMCheckBox           matlab.ui.control.CheckBox
        FactorMEditField          matlab.ui.control.NumericEditField
        FactordemagnificacinpixelescmLabel  matlab.ui.control.Label
        FelipeLpezPadillaLabel    matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        Cargarimagen2Button       matlab.ui.control.Button
        Cargarimagen1Button       matlab.ui.control.Button
        IniciarCorrelacionButton  matlab.ui.control.Button
        FiltroresultadoCheckBox   matlab.ui.control.CheckBox
        FiltroimgenesCheckBox     matlab.ui.control.CheckBox
        TamVerventanaDropDown     matlab.ui.control.DropDown
        TamaoverticalventanaDropDownLabel  matlab.ui.control.Label
        TamHorventanaDropDown     matlab.ui.control.DropDown
        TamaohorizontalventanaDropDownLabel  matlab.ui.control.Label
        tmsEditField              matlab.ui.control.NumericEditField
        tmsEditFieldLabel         matlab.ui.control.Label
        UIAxesImagen2             matlab.ui.control.UIAxes
        UIAxesImagen1             matlab.ui.control.UIAxes
    end

   % INTERFAZ GRAFICA PARA SISTEMA PIV CON IMAGENES
   % AUTOR : FELIPE LOPEZ PADILLA
    properties (Access = private)
        imagen1; % Variable que almacena la imagen 1
        imagen2; %Variable que almacena la imagen 2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarCorrelacionButton
        function IniciarCorrelacionButtonPushed(app, event)
            %Si se tienen las dos imagenes, se aplica la correlacion
            if ~isempty(app.imagen1) && ~isempty(app.imagen2)
                %Se lee el tamaño de ventana
                tam_hor = str2double(app.TamHorventanaDropDown.Value);
                tam_ver = str2double(app.TamVerventanaDropDown.Value);
                %Se lee el intervalo de tiempo
                dt = app.tmsEditField.Value;
                %Se lee si se aplica filtro o no
                FI = app.FiltroimgenesCheckBox.Value;
                FR = app.FiltroresultadoCheckBox.Value;
                %Se aplica la correlacion
                if app.FactorMCheckBox.Value
                    M = app.FactorMEditField.Value;
                    Correlacion(app.imagen1,app.imagen2,[tam_hor,tam_ver],dt,FI,FR,M);
                else
                    Correlacion(app.imagen1,app.imagen2,[tam_hor,tam_ver],dt,FI,FR);
                end
                %Cuando acaba, avisa con un mensaje
                uialert(app.UIFigure,'Correlacion completada',' ','Icon','success');
                
            else %Si no se han elegido las dos imagenes salta ventana de error
                uialert(app.UIFigure,'No se han seleccionado dos imagenes','Error');
            end
        end

        % Button pushed function: Cargarimagen1Button
        function Cargarimagen1ButtonPushed(app, event)
            %Extensiones permitidas para las imagenes
            filtroim = {'*.jpg;*.jpeg;*.png;*.tiff','All Image Files'};
            %Se lee el nombre y ruta de la imagen
            [i, p] = uigetfile(filtroim);
            figure(app.UIFigure);
            % Si se ha elegido un archivo:
            if (ischar(p))
                %Se lee la imagen a partir del nombre
                im = imread(i);
                %Se pasa a escala de grises
                im = im2gray(im);
                %Se pone la grafica en escala de grises
                app.UIAxesImagen1.Colormap = gray(256);
                %Se ajusta la grafica a la imagen
                app.UIAxesImagen1.XLim=[1,size(im,2)];
                app.UIAxesImagen1.YLim=[1,size(im,1)];
                %Se guarda la imagen como variable global
                app.imagen1 = im;
                %Se muestra la imagen en su grafica
                imagesc(app.UIAxesImagen1,im);
            end
        end

        % Button pushed function: Cargarimagen2Button
        function Cargarimagen2ButtonPushed(app, event)
            %Extensiones permitidas para las imagenes
            filtroim = {'*.jpg;*.jpeg;*.png;*.tiff','All Image Files'};
            %Se lee el nombre y ruta de la imagen
            [i, p] = uigetfile(filtroim);
            figure(app.UIFigure);
            % Si se ha elegido un archivo:
            if (ischar(p))
                 %Se lee la imagen a partir del nombre
                im = imread(i);
                %Se pasa a escala de grises
                im = im2gray(im);
                %Se pone la grafica en escala de grises
                app.UIAxesImagen2.Colormap = gray(256);
                %Se ajusta la grafica a la imagen
                app.UIAxesImagen2.XLim=[1,size(im,2)];
                app.UIAxesImagen2.YLim=[1,size(im,1)];
                %Se guarda la imagen como variable global
                app.imagen2 = im;
                %Se muestra la imagen en su grafica
                imagesc(app.UIAxesImagen2,im);
            end
        end

        % Value changed function: FactorMCheckBox
        function FactorMCheckBoxValueChanged(app, event)
            if app.FactorMCheckBox.Value
                app.FactorMEditField.Editable = 'on';
                app.FactorMEditField.Enable = 'on';
            else
                app.FactorMEditField.Editable = 'off';
                app.FactorMEditField.Enable = 'off';                
            end  
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Interfaz Grafica - Imagenes';
            app.UIAxesImagen1 = uiaxes(app.UIFigure);
            title(app.UIAxesImagen1, 'Imagen 1')
            app.UIAxesImagen1.XTick = [];
            app.UIAxesImagen1.YTick = [];
            app.UIAxesImagen2 = uiaxes(app.UIFigure);
            title(app.UIAxesImagen2, 'Imagen 2')
            app.UIAxesImagen2.XTick = [];
            app.UIAxesImagen2.YTick = [];
            app.tmsEditFieldLabel = uilabel(app.UIFigure);
            app.tmsEditFieldLabel.Text = 'Δt (ms)';
            app.tmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.tmsEditField.Limits = [1 Inf];
            app.tmsEditField.Value = 1;
            app.TamaohorizontalventanaDropDownLabel = uilabel(app.UIFigure);
            app.TamaohorizontalventanaDropDownLabel.Text = {'Tamaño horizontal'; 'ventana'};
            app.TamHorventanaDropDown = uidropdown(app.UIFigure);
            app.TamHorventanaDropDown.Items = {'8', '16', '32', '64', '128'};
            app.TamHorventanaDropDown.Value = '8';
            app.TamaoverticalventanaDropDownLabel = uilabel(app.UIFigure);
            app.TamaoverticalventanaDropDownLabel.Text = {'Tamaño vertical'; 'ventana'};
            app.TamVerventanaDropDown = uidropdown(app.UIFigure);
            app.TamVerventanaDropDown.Items = {'8', '16', '32', '64', '128'};
            app.TamVerventanaDropDown.Value = '8';
            app.FiltroimgenesCheckBox = uicheckbox(app.UIFigure);
            app.FiltroimgenesCheckBox.Text = 'Filtro imágenes';
            app.FiltroresultadoCheckBox = uicheckbox(app.UIFigure);
            app.FiltroresultadoCheckBox.Text = 'Filtro resultado';
            app.IniciarCorrelacionButton = uibutton(app.UIFigure, 'push');
            app.IniciarCorrelacionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarCorrelacionButtonPushed, true);
            app.IniciarCorrelacionButton.Text = 'Iniciar Correlación';
            app.Cargarimagen1Button = uibutton(app.UIFigure, 'push');
            app.Cargarimagen1Button.ButtonPushedFcn = createCallbackFcn(app, @Cargarimagen1ButtonPushed, true);
            app.Cargarimagen1Button.Text = 'Cargar imagen 1...';
            app.Cargarimagen2Button = uibutton(app.UIFigure, 'push');
            app.Cargarimagen2Button.ButtonPushedFcn = createCallbackFcn(app, @Cargarimagen2ButtonPushed, true);
            app.Cargarimagen2Button.Text = 'Cargar imagen 2...';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logoPIV.jpg';
            app.FelipeLpezPadillaLabel = uilabel(app.UIFigure);
            app.FelipeLpezPadillaLabel.Text = 'Felipe López Padilla';
            app.FactordemagnificacinpixelescmLabel = uilabel(app.UIFigure);
            app.FactordemagnificacinpixelescmLabel.Text = {'Factor de magnificación'; '(pixeles/cm)'};
            app.FactorMEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorMEditField.Limits = [0.1 Inf];
            app.FactorMEditField.Editable = 'off';
            app.FactorMEditField.Enable = 'off';
            app.FactorMEditField.Value = 0.1;
            app.FactorMCheckBox = uicheckbox(app.UIFigure);
            app.FactorMCheckBox.ValueChangedFcn = createCallbackFcn(app, @FactorMCheckBoxValueChanged, true);
            app.FactorMCheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Imagenes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end