classdef InterfazGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        FelipeLpezPadillaLabel    matlab.ui.control.Label
        CargarimgenesButton       matlab.ui.control.Button
        TamVerVenDropDown         matlab.ui.control.DropDown
        TamaoverticalventanaDropDownLabel  matlab.ui.control.Label
        TamHorVenDropDown         matlab.ui.control.DropDown
        TamaohorizontalventanaDropDownLabel  matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        CargarvideoButton         matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        FactorMCheckBox           matlab.ui.control.CheckBox
        FactorMEditField          matlab.ui.control.NumericEditField
        FactordemagnificacinpixelescmLabel  matlab.ui.control.Label
        FPSLabel                  matlab.ui.control.Label
        BarraFrames               matlab.ui.control.LinearGauge
        Label                     matlab.ui.control.Label
        ResultadoCheckBox         matlab.ui.control.CheckBox
        ImagenesCheckBox          matlab.ui.control.CheckBox
        msLabel                   matlab.ui.control.Label
        tLabel                    matlab.ui.control.Label
        LabelDeltaT               matlab.ui.control.Label
        FrameRateLabelNum         matlab.ui.control.Label
        FramerateLabelEst         matlab.ui.control.Label
        IniciarCorrelacionButton  matlab.ui.control.Button
        LabelFrame2               matlab.ui.control.Label
        LabelFrame1               matlab.ui.control.Label
        SegundoFrameButton        matlab.ui.control.Button
        PrimerFrameButton         matlab.ui.control.Button
        FrameSpinner              matlab.ui.control.Spinner
        FrameSpinnerLabel         matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

   %%INTERFAZ GRAFICA PARA SISTEMA PIV CON VIDEO
   %%AUTOR : FELIPE LOPEZ PADILLA
    properties (Access = private)
        video % Variable que almacena el video elegido
        frame_elegido=1;
    end
    
    methods (Access = public)
        
        function updatevideo(app,video) %Funcion que actualiza el frame mostrado
            %Se lee el frame elegido por el valor del Spinner
            frame = read(video,app.frame_elegido);
            %Se representa en la grafica
            imagesc(app.UIAxes,frame);
        end
    end
    
    methods (Access = private)
        
        function CalculaDeltaT(app)
            if ~isempty(app.LabelFrame1.Text) && ~isempty(app.LabelFrame2.Text)
                %Si se han guardado los dos frames, se calcula el tiempo
                %Se pasa a numero los frames elegidos
                frame1 = str2double(app.LabelFrame1.Text);
                frame2 = str2double(app.LabelFrame2.Text);
                %Se lee los FPS del video
                fps = str2double(app.FrameRateLabelNum.Text);
                %Tiempo = diferencia de frames / FPS (en ms *1000)
                deltat = (frame2-frame1)/fps*1000;
                %Se pasa a texto y se pone en la etiqueta
                deltat = num2str(deltat,'%.2f');
                app.LabelDeltaT.Text = deltat;            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarvideoButton
        function CargarvideoButtonPushed(app, event)
            %Se elien los formatos de vdideo que se deja seleccionar
            filtrov = {'*.avi;*.mov;*.mp4;*.gif','All Video Files'};
            %Se obtiene el nombre del archivo seleccionado y su ruta
            [v, p] = uigetfile(filtrov);
            figure(app.UIFigure);
            % Si se ha seleccionado un archivo:
            if (ischar(p))
                app.UIAxes.Colormap = gray(256); %Escala de grises en la grafica
                %Se lee el video a partir del nombre
                vid = VideoReader(v);
                %Se obtiene el frame rate del video
                app.FrameRateLabelNum.Text = num2str(vid.FrameRate);
                %Se ajusta los ejes al tamaño del video
                app.UIAxes.XLim=[1,vid.Width];
                app.UIAxes.YLim=[1,vid.Height];
                %Se limita el selector de frames
                app.FrameSpinner.Limits = [1,vid.NumFrames];
                app.BarraFrames.Limits=[1, vid.NumFrames];
                app.BarraFrames.MajorTicks=[1:1:vid.NumFrames];
                %Guardamos el video como variable comun
                app.video = vid;
                %Mostramos el frame elegido por pantalla
                updatevideo(app,vid);
            end
        end

        % Button pushed function: PrimerFrameButton
        function PrimerFrameButtonPushed(app, event)
            %Si se pulsa el boton de Primer frame, se guarda el frame
            %elegido
            app.LabelFrame1.Text = num2str(app.FrameSpinner.Value);
            %Se llama a la funcion que calcula el intervalo de tiempo
            CalculaDeltaT(app);
        end

        % Button pushed function: SegundoFrameButton
        function SegundoFrameButtonPushed(app, event)
            %Si se pulsa el boton de Segundo frame, se guarda el frame
            %elegido
            app.LabelFrame2.Text = num2str(app.FrameSpinner.Value);
            %Se llama a la funcion que calcula el intervalo de tiempo
            CalculaDeltaT(app);
        end

        % Button pushed function: IniciarCorrelacionButton
        function IniciarCorrelacionButtonPushed(app, event)
            %Si se tiene video y dos frames seleccionados, se realiza la
            %correlacion
            if ~isempty(app.video) && ~isempty(app.LabelFrame1.Text) && ~isempty(app.LabelFrame2.Text)
                %Se cogen los frames elegidos
                numframe1 = str2double(app.LabelFrame1.Text);
                numframe2 = str2double(app.LabelFrame2.Text);
                %Se leen los frames del video
                frame1 = read(app.video,numframe1);
                frame1 = im2gray(frame1);
                frame2 = read(app.video,numframe2);
                frame2 = im2gray(frame2);
                %Se guarda el tamaño de ventana
                tam_hor = str2double(app.TamHorVenDropDown.Value);
                tam_ver = str2double(app.TamVerVenDropDown.Value);
                %Se lee el intervalo de tiempo
                dt = str2double(app.LabelDeltaT.Text);
                %Se lee si se aplica filtro
                FI = app.ImagenesCheckBox.Value;
                FR = app.ResultadoCheckBox.Value;
                %Se realiza la correlacion
                if app.FactorMCheckBox.Value
                    M = app.FactorMEditField.Value;
                    Correlacion(frame1,frame2,[tam_hor, tam_ver],dt,FI,FR,M);
                else
                    Correlacion(frame1,frame2,[tam_hor, tam_ver],dt,FI,FR);
                end
   
                %Una vez finalizada, se avisa de que se ha completado
                uialert(app.UIFigure,'Correlacion completada',' ','Icon','success');
                
            else %Si falta video o frames, salta un aviso
                uialert(app.UIFigure,'No se han seleccionado video o frames','Error');
            end
        end

        % Value changing function: FrameSpinner
        function FrameSpinnerValueChanging(app, event)
            %Cada vez que se cambia de frame, se actualiza la barra
            changingValue = event.Value;
            app.frame_elegido = changingValue;
            app.BarraFrames.Value = changingValue;
            %LLamamos a la funcion que actualiza el video con el nuevo
            %frame
            updatevideo(app,app.video);
        end

        % Button pushed function: CargarimgenesButton
        function CargarimgenesButtonPushed(app, event)
            %Si se elige el metodo por imagenes, se abre la aplicacion de
            %tratamiento por imagenes y se cierra esta aplicacion
            Imagenes;
        end

        % Value changed function: FactorMCheckBox
        function FactorMCheckBoxValueChanged(app, event)
            if app.FactorMCheckBox.Value
                app.FactorMEditField.Editable = 'on';
                app.FactorMEditField.Enable = 'on';
            else
                app.FactorMEditField.Editable = 'off';
                app.FactorMEditField.Enable = 'off';                
            end   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {720, 720};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {317, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Sistema PIV - Tratamiento de video';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CargarvideoButton = uibutton(app.LeftPanel, 'push');
            app.CargarvideoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarvideoButtonPushed, true);
            app.CargarvideoButton.Text = 'Cargar video...';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'logoPIV.jpg';
            app.TamaohorizontalventanaDropDownLabel = uilabel(app.LeftPanel);
            app.TamaohorizontalventanaDropDownLabel.Text = {'Tamaño horizontal'; 'ventana:'};
            app.TamHorVenDropDown = uidropdown(app.LeftPanel);
            app.TamHorVenDropDown.Items = {'8', '16', '32', '64', '128'};
            app.TamHorVenDropDown.Value = '8';
            app.TamaoverticalventanaDropDownLabel = uilabel(app.LeftPanel);
            app.TamaoverticalventanaDropDownLabel.Text = {'Tamaño vertical'; 'ventana:'};
            app.TamVerVenDropDown = uidropdown(app.LeftPanel);
            app.TamVerVenDropDown.Items = {'8', '16', '32', '64', '128'};
            app.TamVerVenDropDown.Value = '8';
            app.CargarimgenesButton = uibutton(app.LeftPanel, 'push');
            app.CargarimgenesButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimgenesButtonPushed, true);
            app.CargarimgenesButton.Text = 'Cargar imágenes...';
            app.FelipeLpezPadillaLabel = uilabel(app.LeftPanel);
            app.FelipeLpezPadillaLabel.Text = 'Felipe López Padilla';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FrameSpinnerLabel = uilabel(app.RightPanel);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.RightPanel);
            app.FrameSpinner.ValueChangingFcn = createCallbackFcn(app, @FrameSpinnerValueChanging, true);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.Value = 1;
            app.PrimerFrameButton = uibutton(app.RightPanel, 'push');
            app.PrimerFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PrimerFrameButtonPushed, true);
            app.PrimerFrameButton.Text = 'Elegir primer frame';
            app.SegundoFrameButton = uibutton(app.RightPanel, 'push');
            app.SegundoFrameButton.ButtonPushedFcn = createCallbackFcn(app, @SegundoFrameButtonPushed, true);
            app.SegundoFrameButton.Text = 'Elegir segundo frame';
            app.LabelFrame1 = uilabel(app.RightPanel);
            app.LabelFrame1.Text = '';
            app.LabelFrame2 = uilabel(app.RightPanel);
            app.LabelFrame2.Text = '';
            app.IniciarCorrelacionButton = uibutton(app.RightPanel, 'push');
            app.IniciarCorrelacionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarCorrelacionButtonPushed, true);
            app.IniciarCorrelacionButton.Text = 'Iniciar Correlacion';
            app.FramerateLabelEst = uilabel(app.RightPanel);
            app.FramerateLabelEst.Text = 'Framerate =';
            app.FrameRateLabelNum = uilabel(app.RightPanel);
            app.FrameRateLabelNum.Text = '';
            app.LabelDeltaT = uilabel(app.RightPanel);
            app.LabelDeltaT.Text = '';
            app.tLabel = uilabel(app.RightPanel);
            app.tLabel.Text = 'Δt = ';
            app.msLabel = uilabel(app.RightPanel);
            app.msLabel.Text = 'ms';
            app.ImagenesCheckBox = uicheckbox(app.RightPanel);
            app.ImagenesCheckBox.Text = 'Filtro imágenes';
            app.ResultadoCheckBox = uicheckbox(app.RightPanel);
            app.ResultadoCheckBox.Text = 'Filtro resultado';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.BarraFrames = uigauge(app.RightPanel, 'linear');
            app.FPSLabel = uilabel(app.RightPanel);
            app.FPSLabel.Text = 'FPS';
            app.FactordemagnificacinpixelescmLabel = uilabel(app.RightPanel);
            app.FactordemagnificacinpixelescmLabel.Text = {'Factor de magnificación'; '(pixeles/cm)'};
            app.FactorMEditField = uieditfield(app.RightPanel, 'numeric');
            app.FactorMEditField.Limits = [0.1 Inf];
            app.FactorMEditField.Editable = 'off';
            app.FactorMEditField.Enable = 'off';
            app.FactorMEditField.Value = 0.1;
            app.FactorMCheckBox = uicheckbox(app.RightPanel);
            app.FactorMCheckBox.ValueChangedFcn = createCallbackFcn(app, @FactorMCheckBoxValueChanged, true);
            app.FactorMCheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazGrafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end